classdef Thermal_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TemperatureRangeButtonGroup  matlab.ui.container.ButtonGroup
        edt_temperature_range        matlab.ui.control.EditField
        ManualButton                 matlab.ui.control.ToggleButton
        AutoButton                   matlab.ui.control.ToggleButton
        lamp_status                  matlab.ui.control.Lamp
        txt_subject                  matlab.ui.control.Label
        txt_line_number              matlab.ui.control.Label
        txt_buffer_size              matlab.ui.control.Label
        btn_roi_add                  matlab.ui.control.Button
        btn_roi_del                  matlab.ui.control.Button
        btn_save                     matlab.ui.control.Button
        btn_next                     matlab.ui.control.Button
        btn_prev                     matlab.ui.control.Button
        btn_open                     matlab.ui.control.Button
        BEHAVIORButtonGroup          matlab.ui.container.ButtonGroup
        radio_sleeping               matlab.ui.control.RadioButton
        radio_moving                 matlab.ui.control.RadioButton
        radio_climbing               matlab.ui.control.RadioButton
        radio_rearing                matlab.ui.control.RadioButton
        radio_none                   matlab.ui.control.RadioButton
        PercentageLabel              matlab.ui.control.Label
        gauge_perc                   matlab.ui.control.SemicircularGauge
        ax_thresh                    matlab.ui.control.UIAxes
        ax_main                      matlab.ui.control.UIAxes
        ax_frame                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        path_folder % Description
        path_img % Description
        subject_name % Description
        file_mng % Description
        current_image
        behaviors % Description
        temperature_range % Description
        temperature_ismanual % Description
    end
    
    methods (Access = private)
        
        function plotter(app)
            
            if app.temperature_ismanual
                imagesc(app.ax_frame, app.current_image.img);
                clim(app.ax_frame, app.temperature_range);
            else
                clim(app.ax_frame, 'auto');
                imagesc(app.ax_frame, app.current_image.img);
            end
            imagesc(app.ax_thresh, app.current_image.label);
            imagesc(app.ax_main, app.current_image.fusion);
            title(app.ax_main,['Frame ID:', num2str(app.current_image.id)]);
            title(app.ax_thresh,'Threshold Selection:');
            title(app.ax_frame,'Thermal Frame');

            app.txt_buffer_size.Text = ['Buffer Size: ', num2str(app.file_mng.img_buffer_n_elem)];
            app.txt_line_number.Text = ['Line ', num2str( app.file_mng.currentLine-1 ), ' of ', num2str(app.file_mng.totalLineFrames) ];

            
            app.gauge_perc.Value = app.file_mng.progress * 100;
            app.changeRadioBeh(app.current_image.behavior.name);

            
            if app.current_image.is_editable
                app.BEHAVIORButtonGroup.Enable='on';
                app.btn_roi_add.Enable="on";
                app.btn_roi_del.Enable="on";
            else
                app.BEHAVIORButtonGroup.Enable='off';
                app.btn_roi_add.Enable="off";
                app.btn_roi_del.Enable="off";
            end

            drawnow;
        end
        
        function vec = beh2vec(app, beh)
            vec = ismember(app.behaviors,beh);
        end

        function vec = vec2beh(app, vec)
            vec = app.behaviors(find(vec));
        end

        
        function changeRadioBeh(app,beh)
            for i = 1:length(app.current_image.behavior.vec)
                app.BEHAVIORButtonGroup.Buttons(i).Value = app.current_image.behavior.vec(i);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Loading...')
            app.lamp_status.Color = 'magenta';
            app.btn_roi_add.Enable="off";
            app.btn_roi_del.Enable="off";
            app.btn_next.Enable="off";
            app.btn_prev.Enable="off";
            app.btn_save.Enable='off';
            app.btn_open.Enable='off';
            drawnow;

            app.temperature_range = [20,30];
            app.edt_temperature_range.Value = num2str(app.temperature_range );
            app.edt_temperature_range.Enable = 'off';
            app.AutoButton.Value = 1;
            app.temperature_ismanual = false;

            app.path_folder = uigetdir('C:\Users\pupil\Downloads\test_24h\20230511_170231-ROI_1-tizio','Select subject:');
            app.path_img = fullfile(app.path_folder,'img.csv');
            if ~(exist(app.path_img,'file')==2)
                error('img file does not exist')
            end
            app.file_mng = RecManager_id(app.path_folder);
            
            app.behaviors = {app.BEHAVIORButtonGroup.Buttons(:).Text};

            app.gauge_perc.Value = app.file_mng.progress * 100;
            [a,app.subject_name] = fileparts(app.path_img);
            
            [app.current_image.img , app.current_image.label, app.current_image.beh, app.current_image.id, app.current_image.is_editable] = app.file_mng.getFrameCurrent;
            app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
            
            app.current_image.behavior.name = app.current_image.beh;
            app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);


            plotter(app);
            
            if app.current_image.is_editable
                app.btn_roi_add.Enable="on";
                app.btn_roi_del.Enable="on";
            end
            
            app.btn_save.Enable = "off";
            app.btn_next.Enable = "on";
            app.btn_prev.Enable = "on";
            app.btn_open.Enable = 'on';
            app.lamp_status.Color = 'green';
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if app.file_mng.img_buffer_n_elem>0
                answer = questdlg('Non saved elements in the buffer. Do you really want to quit?','Closing...', 'Quit', 'Cancel', 'Cancel');

                if isempty(answer) || strcmpi(answer,'cancel')
                    return
                end
            end
            disp('Closing...')
            if ~isempty(app.file_mng)
                disp('Closing img file..')
                app.file_mng.close;
            end
            disp('File Closed.')
            delete(app)
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            app.lamp_status.Color = 'yellow';
            if app.file_mng.hasNext
                [app.current_image.img , app.current_image.label, app.current_image.behavior.name, app.current_image.id, app.current_image.is_editable] = app.file_mng.getFrameNext;
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
                app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
                plotter(app);
            end

            if ~app.file_mng.hasNext
                app.btn_next.Enable="off";
            end

            if app.file_mng.hasPrevious
                app.btn_prev.Enable="on";
            end

            if app.file_mng.img_buffer_n_elem>0
                app.btn_save.Enable = "on";
            else
                app.btn_save.Enable = "off";
            end

            app.lamp_status.Color = 'green';
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)
            app.lamp_status.Color = 'yellow';
            if app.file_mng.hasPrevious
                [app.current_image.img , app.current_image.label, app.current_image.behavior.name, app.current_image.id, app.current_image.is_editable] = app.file_mng.getFramePrevious;
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
                app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
                plotter(app);
            end

            if app.file_mng.hasNext
                app.btn_next.Enable="on";
            end

            if ~app.file_mng.hasPrevious
                app.btn_prev.Enable="off";
            end
            
            app.lamp_status.Color = 'green';
            drawnow;
        end

        % Button pushed function: btn_open
        function btn_openButtonPushed(app, event)
            disp('Not implemented')
        end

        % Button pushed function: btn_save
        function btn_saveButtonPushed(app, event)
            app.lamp_status.Color = 'magenta';
            app.btn_roi_add.Enable="off";
            app.btn_roi_del.Enable="off";
            app.btn_next.Enable="off";
            app.btn_prev.Enable="off";
            app.btn_save.Enable='off';
            app.btn_open.Enable='off';
            drawnow;
            app.file_mng.writeBuffer();

            app.btn_roi_add.Enable="on";
            app.btn_roi_del.Enable="on";
            app.btn_next.Enable="on";
            app.btn_prev.Enable="on";
            app.btn_open.Enable='on';
            app.lamp_status.Color = 'green';
            app.btn_save.Enable = "off";
            drawnow;
        end

        % Selection changed function: BEHAVIORButtonGroup
        function BEHAVIORButtonGroupSelectionChanged(app, event)
            selectedButton = app.BEHAVIORButtonGroup.SelectedObject;
            app.current_image.behavior.name = selectedButton.Text;
            app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
            
            app.file_mng.editLabel(app.current_image.label, app.current_image.behavior.name, app.current_image.id);
        end

        % Button pushed function: btn_roi_del
        function btn_roi_delButtonPushed(app, event)
            app.lamp_status.Color = 'yellow';
            app.btn_roi_add.Enable="off";
            app.btn_roi_del.Enable="off";
            app.btn_next.Enable="off";
            app.btn_prev.Enable="off";
            app.btn_open.Enable='off';
            drawnow;
            roi = drawfreehand(app.ax_main);
            wait(roi);
            if roi.Position
                bw = createMask(roi);
                delete(roi)
                app.current_image.label = (app.current_image.label - bw) > 0;
                app.file_mng.editLabel(app.current_image.label, app.current_image.behavior.name, app.current_image.id);
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
            else
                delete(roi)
            end
            plotter(app);

            app.btn_roi_add.Enable="on";
            app.btn_roi_del.Enable="on";
            app.btn_next.Enable="on";
            app.btn_prev.Enable="on";
            app.btn_open.Enable='on';
            app.lamp_status.Color = 'green';
            drawnow;
        end

        % Button pushed function: btn_roi_add
        function btn_roi_addButtonPushed(app, event)
            app.lamp_status.Color = 'yellow';
            app.btn_roi_add.Enable="off";
            app.btn_roi_del.Enable="off";
            app.btn_next.Enable="off";
            app.btn_prev.Enable="off";
            app.btn_open.Enable='off';
            drawnow;
            roi = drawfreehand(app.ax_main); 
            wait(roi);
            if roi.Position
                bw = createMask(roi);
                delete(roi)
                app.current_image.label = bw | app.current_image.label;
                app.file_mng.editLabel(app.current_image.label,app.current_image.beh,app.current_image.id);
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
            else
                delete(roi)
            end
            plotter(app);

            app.btn_roi_add.Enable="on";
            app.btn_roi_del.Enable="on";
            app.btn_next.Enable="on";
            app.btn_prev.Enable="on";
            app.btn_open.Enable='on';
            app.lamp_status.Color = 'green';
            drawnow;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    app.btn_prevButtonPushed([]);
                case 'rightarrow'
                    app.btn_nextButtonPushed([]);
                case 'n'
                    if strcmpi(app.BEHAVIORButtonGroup.Enable,'on')
                        app.current_image.behavior.name = 'NONE';
                        app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
                        app.changeRadioBeh(app.current_image.behavior.name);
                        app.file_mng.editLabel(app.current_image.label, app.current_image.behavior.name, app.current_image.id);
                    end
                case 'r'
                    if strcmpi(app.BEHAVIORButtonGroup.Enable,'on')
                        app.current_image.behavior.name = 'REARING';
                        app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
                        app.changeRadioBeh(app.current_image.behavior.name);
                        app.file_mng.editLabel(app.current_image.label, app.current_image.behavior.name, app.current_image.id);
                    end
                case 'h'
                    if strcmpi(app.BEHAVIORButtonGroup.Enable,'on')
                        app.current_image.behavior.name = 'HANGING';
                        app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
                        app.changeRadioBeh(app.current_image.behavior.name);
                        app.file_mng.editLabel(app.current_image.label, app.current_image.behavior.name, app.current_image.id);
                    end
                case 'a'
                    if strcmpi(app.BEHAVIORButtonGroup.Enable,'on')
                        app.current_image.behavior.name = 'ACTIVE';
                        app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
                        app.changeRadioBeh(app.current_image.behavior.name);
                        app.file_mng.editLabel(app.current_image.label, app.current_image.behavior.name, app.current_image.id);
                    end
                case 's'
                    if strcmpi(app.BEHAVIORButtonGroup.Enable,'on')
                        app.current_image.behavior.name = 'SLEEP';
                        app.current_image.behavior.vec = app.beh2vec(app.current_image.behavior.name);
                        app.changeRadioBeh(app.current_image.behavior.name);
                        app.file_mng.editLabel(app.current_image.label, app.current_image.behavior.name, app.current_image.id);
                    end
                otherwise
                    disp(key)

            end
        end

        % Selection changed function: TemperatureRangeButtonGroup
        function TemperatureRangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TemperatureRangeButtonGroup.SelectedObject;
            disp(selectedButton)
            if strcmpi(selectedButton.Text,'auto')
                app.edt_temperature_range.Enable = 'off';
                app.temperature_ismanual = false;
            else
                app.edt_temperature_range.Enable = 'on';
                app.temperature_range = str2num(app.edt_temperature_range.Value);
                app.temperature_ismanual = true;
            end
            plotter(app)
        end

        % Value changed function: edt_temperature_range
        function edt_temperature_rangeValueChanged(app, event)
            value = app.edt_temperature_range.Value;
            if app.temperature_ismanual
                try
                    app.temperature_range = str2num(value);
                catch
                    app.edt_temperature_range.Value = app.temperature_range;
                end
                plotter(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ax_frame = uiaxes(app.UIFigure);
            title(app.ax_frame, 'Title')
            zlabel(app.ax_frame, 'Z')
            app.ax_frame.XTick = [];
            app.ax_frame.YTick = [];
            app.ax_main = uiaxes(app.UIFigure);
            title(app.ax_main, 'Title')
            zlabel(app.ax_main, 'Z')
            app.ax_main.XTick = [];
            app.ax_main.YTick = [];
            app.ax_thresh = uiaxes(app.UIFigure);
            title(app.ax_thresh, 'Title')
            app.ax_thresh.XTick = [];
            app.ax_thresh.YTick = [];
            app.gauge_perc = uigauge(app.UIFigure, 'semicircular');
            app.PercentageLabel = uilabel(app.UIFigure);
            app.PercentageLabel.Text = 'Percentage';
            app.BEHAVIORButtonGroup = uibuttongroup(app.UIFigure);
            app.BEHAVIORButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BEHAVIORButtonGroupSelectionChanged, true);
            app.BEHAVIORButtonGroup.Title = 'BEHAVIOR';
            app.radio_none = uiradiobutton(app.BEHAVIORButtonGroup);
            app.radio_none.Text = 'NONE';
            app.radio_none.Value = true;
            app.radio_rearing = uiradiobutton(app.BEHAVIORButtonGroup);
            app.radio_rearing.Text = 'REARING';
            app.radio_climbing = uiradiobutton(app.BEHAVIORButtonGroup);
            app.radio_climbing.Text = 'HANGING';
            app.radio_moving = uiradiobutton(app.BEHAVIORButtonGroup);
            app.radio_moving.Text = 'ACTIVE';
            app.radio_sleeping = uiradiobutton(app.BEHAVIORButtonGroup);
            app.radio_sleeping.Text = 'SLEEP';
            app.btn_open = uibutton(app.UIFigure, 'push');
            app.btn_open.ButtonPushedFcn = createCallbackFcn(app, @btn_openButtonPushed, true);
            app.btn_open.Text = 'OPEN';
            app.btn_prev = uibutton(app.UIFigure, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Text = 'PREV';
            app.btn_next = uibutton(app.UIFigure, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Text = 'NEXT';
            app.btn_save = uibutton(app.UIFigure, 'push');
            app.btn_save.ButtonPushedFcn = createCallbackFcn(app, @btn_saveButtonPushed, true);
            app.btn_save.Text = 'SAVE';
            app.btn_roi_del = uibutton(app.UIFigure, 'push');
            app.btn_roi_del.ButtonPushedFcn = createCallbackFcn(app, @btn_roi_delButtonPushed, true);
            app.btn_roi_del.Text = '-';
            app.btn_roi_add = uibutton(app.UIFigure, 'push');
            app.btn_roi_add.ButtonPushedFcn = createCallbackFcn(app, @btn_roi_addButtonPushed, true);
            app.btn_roi_add.Text = '+';
            app.txt_buffer_size = uilabel(app.UIFigure);
            app.txt_buffer_size.Text = 'Buffer Size: 0';
            app.txt_line_number = uilabel(app.UIFigure);
            app.txt_line_number.Text = 'Line 0 of 0';
            app.txt_subject = uilabel(app.UIFigure);
            app.txt_subject.Text = 'Subject: None';
            app.lamp_status = uilamp(app.UIFigure);
            app.TemperatureRangeButtonGroup = uibuttongroup(app.UIFigure);
            app.TemperatureRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TemperatureRangeButtonGroupSelectionChanged, true);
            app.TemperatureRangeButtonGroup.Title = 'Temperature Range';
            app.AutoButton = uitogglebutton(app.TemperatureRangeButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uitogglebutton(app.TemperatureRangeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.edt_temperature_range = uieditfield(app.TemperatureRangeButtonGroup, 'text');
            app.edt_temperature_range.ValueChangedFcn = createCallbackFcn(app, @edt_temperature_rangeValueChanged, true);
            app.edt_temperature_range.Value = '20 30';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thermal_labeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end